classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LoadImageButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        SelectpointsButton   matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        SegmentsButtonGroup  matlab.ui.container.ButtonGroup
        Button_1             matlab.ui.control.ToggleButton
        Button_2             matlab.ui.control.ToggleButton
        Button_3             matlab.ui.control.ToggleButton
        Button_4             matlab.ui.control.ToggleButton
        Button_5             matlab.ui.control.ToggleButton
        Button_7             matlab.ui.control.ToggleButton
        Button_8             matlab.ui.control.ToggleButton
        Button_9             matlab.ui.control.ToggleButton
        Button_6             matlab.ui.control.ToggleButton
        Button_10            matlab.ui.control.ToggleButton
        TabGroup             matlab.ui.container.TabGroup
        DataanalysisTab      matlab.ui.container.Tab
        HistogramTab         matlab.ui.container.Tab
    end


    properties (Access = public)
        production,
        InputImage, % Description,
        GrayImage,
        Points
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, production)
            close all;
            app.production = production;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             warning('off','MATLAB:imagesci:tifftagsread:badTagValueDivisionByZero')
            
             if app.production
                [file,path] = uigetfile("*.jpg", 'Select a FLIR Image File');
                % workaround
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
                fullpath = fullfile(path,file);
             else
                fullpath = 'C:\Users\Dawid\Documents\GitHub\praca magisterska\back-flir\example.jpg';
             end
            
            I = importdata(fullpath);
            app.InputImage = I;
            gray = rgb2gray(I);
            app.GrayImage = gray;
     
            imagesc(app.UIAxes, gray);
            xlim(app.UIAxes, [0 size(gray,2)]);
            ylim(app.UIAxes, [0 size(gray,1)]);
        end

        % Button pushed function: SelectpointsButton
        function SelectpointsButtonPushed(app, event)
            f = figure
            gray = app.GrayImage;
            imagesc(gray);
            
            if app.production
                pointsNumber = 10;
                
                app.Points.X = [];
                app.Points.Y = [];
                
                for i=1:pointsNumber
                    [x, y] = ginput(1);
                    
                    hold on
                    plot(x(1), y(1), 'r*');
                    text(x, y, ['  ', num2str(i)], 'Color','red');
                    drawnow
                    app.Points.X = [app.Points.X x];
                    app.Points.Y = [app.Points.Y y];
                end
            else 
                % testing points start
                app.Points.X = [1.125174953959484e+02 1.354051565377532e+02 1.626942909760589e+02 1.552117863720074e+02 1.508103130755064e+02 1.538913443830571e+02 96.672191528545110 98.432780847145500 94.911602209944760 84.788213627992630];
                app.Points.Y = [81.621495327102850 83.116822429906590 96.574766355140240 1.369485981308412e+02 1.504065420560748e+02 1.683504672897197e+02 1.698457943925234e+02 1.519018691588786e+02 1.399392523364487e+02 98.817757009345830];
                hold on
                plot(app.Points.X, app.Points.Y, 'r*');
                % testing points end
            end
            
            figure
            BW = poly2mask(app.Points.X, app.Points.Y, size(gray, 1), size(gray,2));
            merged = uint8(BW).*app.GrayImage;
            imagesc(uint8(BW).*app.GrayImage);
%             close(f)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obraz FLIR')
            app.SelectpointsButton = uibutton(app.UIFigure, 'push');
            app.SelectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointsButtonPushed, true);
            app.SelectpointsButton.Text = 'Select points';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.Text = 'Info';
            app.SegmentsButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentsButtonGroup.Title = 'Segments';
            app.Button_1 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_5.Text = '5';
            app.Button_7 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_7.Text = '7';
            app.Button_8 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_8.Text = '8';
            app.Button_9 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_9.Text = '9';
            app.Button_6 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_6.Text = '6';
            app.Button_10 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_10.Text = '10';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataanalysisTab = uitab(app.TabGroup);
            app.DataanalysisTab.Title = 'Data analysis';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end